classdef soccer_areodynamics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GenerateGraphsButton          matlab.ui.control.Button
        Densitykgm3EditFieldLabel     matlab.ui.control.Label
        Densitykgm3EditField          matlab.ui.control.NumericEditField
        BallSpeedmsEditFieldLabel     matlab.ui.control.Label
        BallSpeedmsEditField          matlab.ui.control.NumericEditField
        DiametermEditFieldLabel       matlab.ui.control.Label
        DiametermEditField            matlab.ui.control.NumericEditField
        ViscosityPasEditFieldLabel    matlab.ui.control.Label
        ViscosityPasEditField         matlab.ui.control.NumericEditField
        ReynoldsNumberLabel           matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        GenerateReButton              matlab.ui.control.Button
        C_DEditFieldLabel             matlab.ui.control.Label
        C_DEditField                  matlab.ui.control.NumericEditField
        AngularVelocitydegsEditFieldLabel  matlab.ui.control.Label
        AngularVelocitydegsEditField  matlab.ui.control.NumericEditField
        BallVelocityDirectionXZEditFieldLabel  matlab.ui.control.Label
        BallVelocityDirectionXZEditField  matlab.ui.control.NumericEditField
        BallMasskgEditFieldLabel      matlab.ui.control.Label
        BallMasskgEditField           matlab.ui.control.NumericEditField
        C_LEditFieldLabel             matlab.ui.control.Label
        C_LEditField                  matlab.ui.control.NumericEditField
        AngularVelocityDirectionEditField_2Label  matlab.ui.control.Label
        AngularVelocityDirectionEditField  matlab.ui.control.NumericEditField
        BallVelocityDirectionXYEditFieldLabel  matlab.ui.control.Label
        BallVelocityDirectionXYEditField  matlab.ui.control.NumericEditField
        C_WEditFieldLabel             matlab.ui.control.Label
        C_WEditField                  matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        DistanceTab                   matlab.ui.container.Tab
        Distance                      matlab.ui.control.UIAxes
        VariationTab                  matlab.ui.container.Tab
        Variation                     matlab.ui.control.UIAxes
        HeightTab                     matlab.ui.container.Tab
        Height                        matlab.ui.control.UIAxes
        VarbyDisTab                   matlab.ui.container.Tab
        VarByDist                     matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        dTab                          matlab.ui.container.Tab
        ThreeD                        matlab.ui.control.UIAxes
        Table                         matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateGraphsButton
        function GenerateGraphsButtonPushed(app, event)
            % Parameters
            g = 9.81; 
            density = app.Densitykgm3EditField.Value; 
            Cd = app.C_DEditField.Value; 
            diameter = app.DiametermEditField.Value;
            A = pi*(diameter/2)^2; 
            mass = app.BallMasskgEditField.Value;
            Cl = app.C_LEditField.Value;
            Cw = app.C_WEditField.Value;
            alpha = 0.5 * density * Cl * A;
            
            dt = 0.01;
            g_v = g * dt;
            time = [0];
            xPositions = [0];
            yPositions = [0];
            zPositions = [0];
            
            %user input
            v_magnitude = app.BallSpeedmsEditField.Value;
            v_xy_theta = deg2rad(app.BallVelocityDirectionXYEditField.Value);
            v_xz_theta = deg2rad(app.BallVelocityDirectionXZEditField.Value);         
            w_magnitude = deg2rad(app.AngularVelocitydegsEditField.Value);
            w_theta = deg2rad(app.AngularVelocityDirectionEditField.Value);
            
            % Velocity components
            vx = v_magnitude * cos(v_xz_theta) * cos(v_xy_theta);
            vy = v_magnitude * cos(v_xz_theta) * sin(v_xy_theta);
            vz = v_magnitude * sin(v_xz_theta);
            velocity = [vx, vy, vz]; 
            
            % Angular velocity components
            wx = w_magnitude * sin(w_theta) * sin(v_xy_theta);
            wy = w_magnitude * sin(w_theta) * cos(v_xy_theta);
            wz = w_magnitude * cos(w_theta);
            w0 = [wx, wy, wz];
            
   
            i = 2;
            while zPositions(i-1) >= 0 && time(i-1) < 5
                % drag force calculations
                v_magnitude = norm(velocity);
                Fd = 0.5 * density * Cd * A * v_magnitude^2;
                drag_direction = -velocity / v_magnitude;
                aFd = Fd / mass .* drag_direction;
                vFd = aFd * dt;
           
                % Magnus force calculation
                w = w0 .* exp((-Cw / (diameter/2)) * time(i-1));         
                Fm = alpha * cross(w, velocity);
                aFm = Fm / mass;
                vFm = aFm * dt;
                
                % Update velocities
                vx = vx + vFd(1) + vFm(1);
                vy = vy + vFd(2) + vFm(2);
                vz = vz - g_v + vFd(3) + vFm(3);
                velocity = [vx, vy, vz];
            
                % Update positions
                xPositions(i) = xPositions(i-1) + vx * dt;
                yPositions(i) = yPositions(i-1) + vy * dt;
                zPositions(i) = zPositions(i-1) + vz * dt;
                
                % Update time step
                time(i) = time(i-1) + dt;
                i = i + 1;
            end
            
            % Graph Components
            tableData = table(time', xPositions', yPositions', zPositions');
            app.UITable.Data = tableData;
            
            maxDistance = 40;
            maxVariation = 12;
            minVariation = -12;
            maxHeight = 25;
            minHeight = 0;
            
            ylim(app.Distance, [0 maxDistance]);
            ylim(app.Variation, [minVariation maxVariation]);
            ylim(app.Height, [minHeight maxHeight]);
            
            xlim(app.VarByDist, [0 maxDistance]);
            ylim(app.VarByDist, [minVariation maxVariation]);
            
            xlim(app.ThreeD, [0 maxDistance]);
            ylim(app.ThreeD, [minVariation, maxVariation]);
            zlim(app.ThreeD, [minHeight maxHeight])
            
            
            plot(app.Distance, time, xPositions);
            plot(app.Variation, time, yPositions);
            plot(app.Height, time, zPositions);
            plot(app.VarByDist, xPositions, yPositions);
            plot3(app.ThreeD, xPositions, yPositions, zPositions);

        end

        % Button pushed function: GenerateReButton
        function GenerateReButtonPushed(app, event)
            
            density = app.Densitykgm3EditField.Value;
            velocity = app.BallSpeedmsEditField.Value;
            viscosity = app.ViscosityPasEditField.Value;
            diameter = app.DiametermEditField.Value;
            Re = density * velocity * diameter / viscosity;
            app.Label.Text = num2str(Re, '%.2e');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateGraphsButton = uibutton(app.LeftPanel, 'push');
            app.GenerateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphsButtonPushed, true);
            app.GenerateGraphsButton.Text = 'Generate Graphs';
            app.Densitykgm3EditFieldLabel = uilabel(app.LeftPanel);
            app.Densitykgm3EditFieldLabel.Text = 'Density  kg/m^3';
            app.Densitykgm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Densitykgm3EditField.Value = 1.2;
            app.BallSpeedmsEditFieldLabel = uilabel(app.LeftPanel);
            app.BallSpeedmsEditFieldLabel.Text = 'Ball Speed  m/s';
            app.BallSpeedmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BallSpeedmsEditField.Value = 25;
            app.DiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.DiametermEditFieldLabel.Text = 'Diameter m';
            app.DiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiametermEditField.Value = 0.22;
            app.ViscosityPasEditFieldLabel = uilabel(app.LeftPanel);
            app.ViscosityPasEditFieldLabel.Text = 'Viscosity Pa*s';
            app.ViscosityPasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ViscosityPasEditField.Value = 1.8e-05;
            app.ReynoldsNumberLabel = uilabel(app.LeftPanel);
            app.ReynoldsNumberLabel.Text = 'Reynolds Number:';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '0';
            app.GenerateReButton = uibutton(app.LeftPanel, 'push');
            app.GenerateReButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReButtonPushed, true);
            app.GenerateReButton.Text = 'Generate Re ';
            app.C_DEditFieldLabel = uilabel(app.LeftPanel);
            app.C_DEditFieldLabel.Text = 'C_D';
            app.C_DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_DEditField.Value = 0.25;
            app.AngularVelocitydegsEditFieldLabel = uilabel(app.LeftPanel);
            app.AngularVelocitydegsEditFieldLabel.Text = 'Angular Velocity deg/s';
            app.AngularVelocitydegsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngularVelocitydegsEditField.Value = 3600;
            app.BallVelocityDirectionXZEditFieldLabel = uilabel(app.LeftPanel);
            app.BallVelocityDirectionXZEditFieldLabel.Text = 'Ball Velocity Direction X-Z';
            app.BallVelocityDirectionXZEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BallVelocityDirectionXZEditField.Value = 40;
            app.BallMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.BallMasskgEditFieldLabel.Text = 'Ball Mass kg';
            app.BallMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BallMasskgEditField.Value = 0.43;
            app.C_LEditFieldLabel = uilabel(app.LeftPanel);
            app.C_LEditFieldLabel.Text = 'C_L';
            app.C_LEditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_LEditField.Value = 0.25;
            app.AngularVelocityDirectionEditField_2Label = uilabel(app.LeftPanel);
            app.AngularVelocityDirectionEditField_2Label.Text = 'Angular Velocity Direction';
            app.AngularVelocityDirectionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngularVelocityDirectionEditField.Value = 30;
            app.BallVelocityDirectionXYEditFieldLabel = uilabel(app.LeftPanel);
            app.BallVelocityDirectionXYEditFieldLabel.Text = 'Ball Velocity Direction X-Y';
            app.BallVelocityDirectionXYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_WEditFieldLabel = uilabel(app.LeftPanel);
            app.C_WEditFieldLabel.Text = 'C_W';
            app.C_WEditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_WEditField.Value = 0.1;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DistanceTab = uitab(app.TabGroup);
            app.DistanceTab.Title = 'Distance';
            app.Distance = uiaxes(app.DistanceTab);
            title(app.Distance, 'Distance V Time')
            xlabel(app.Distance, 'Time (sec)')
            ylabel(app.Distance, 'Distance (m)')
            zlabel(app.Distance, 'Z')
            app.VariationTab = uitab(app.TabGroup);
            app.VariationTab.Title = 'Variation';
            app.Variation = uiaxes(app.VariationTab);
            title(app.Variation, 'Variation V Time')
            xlabel(app.Variation, 'Time (sec)')
            ylabel(app.Variation, 'Variation (m)')
            zlabel(app.Variation, 'Z')
            app.HeightTab = uitab(app.TabGroup);
            app.HeightTab.Title = 'Height';
            app.Height = uiaxes(app.HeightTab);
            title(app.Height, 'Height V Time')
            xlabel(app.Height, 'Time (sec)')
            ylabel(app.Height, 'Height (m)')
            zlabel(app.Height, 'Z')
            app.VarbyDisTab = uitab(app.TabGroup);
            app.VarbyDisTab.Title = 'Var by Dis';
            app.VarByDist = uiaxes(app.VarbyDisTab);
            title(app.VarByDist, 'Varation V Distance')
            xlabel(app.VarByDist, 'Distance')
            ylabel(app.VarByDist, {'Variation'; ''})
            zlabel(app.VarByDist, 'Z')
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.dTab = uitab(app.TabGroup2);
            app.dTab.Title = '3d';
            app.ThreeD = uiaxes(app.dTab);
            title(app.ThreeD, 'Position')
            xlabel(app.ThreeD, 'Distance')
            ylabel(app.ThreeD, 'Variation')
            zlabel(app.ThreeD, 'Height')
            app.Table = uitab(app.TabGroup2);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'Time'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soccer_areodynamics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end